classdef dataviz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        DisplayIntensityProfileCheckBox  matlab.ui.control.CheckBox
        XSliderLabel                matlab.ui.control.Label
        XSlider                     matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        YSlider                     matlab.ui.control.Slider
        UnitsDropDownLabel          matlab.ui.control.Label
        UnitsDropDown               matlab.ui.control.DropDown
        PlaneButtonGroup            matlab.ui.container.ButtonGroup
        xyButton                    matlab.ui.control.RadioButton
        xzButton                    matlab.ui.control.RadioButton
        yzButton                    matlab.ui.control.RadioButton
        FocusLabel                  matlab.ui.control.Label
        FXSliderLabel               matlab.ui.control.Label
        FXSlider                    matlab.ui.control.Slider
        FYSliderLabel               matlab.ui.control.Label
        FYSlider                    matlab.ui.control.Slider
        FZSliderLabel               matlab.ui.control.Label
        FZSlider                    matlab.ui.control.Slider
        ResettoFocusButton          matlab.ui.control.Button
        FlipIntensityProfileSwitch  matlab.ui.control.Switch
        TimeSliderLabel             matlab.ui.control.Label
        TimeSlider                  matlab.ui.control.Slider
    end

    
    properties (Access = public)
        data % sensor data
        kgrid % kgrid defined for the simulation sensor data came from
        current_params % struct simulation parameters
        x % x vector of sensor mask
        y % y vector of sensor mask
        z % z vector of sensor mask
        max_p
        min_p
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, kwave_struct)
            fields = fieldnames(kwave_struct);
            for i = 1:length(fields)
                if strcmp('kgrid',fields{i})
                    app.kgrid = kwave_struct.kgrid;
                else
                    app.data = kwave_struct.(fields{i});
                    app.current_params = fieldname_to_param(fields{i});
                end
            end
            app = configure_app(app);
            plot_kwave(app);
        end

        % Callback function
        function TimeSliderValueChanged(app, event)
            
        end

        % Value changed function: DisplayIntensityProfileCheckBox
        function DisplayIntensityProfileCheckBoxValueChanged(app, event)
            plot_kwave(app);
        end

        % Value changed function: FlipIntensityProfileSwitch
        function FlipIntensityProfileSwitchValueChanged(app, event)
            plot_kwave(app);
        end

        % Button pushed function: ResettoFocusButton
        function ResettoFocusButtonPushed(app, event)
            switch app.current_params.Slice
                case 'xy'
                    app.XSlider.Value = app.current_params.FX;
                    app.YSlider.Value = app.current_params.FY;
                case 'xz'
                    app.XSlider.Value = app.current_params.FX;
                    app.YSlider.Value = app.current_params.FZ;
                case 'yz'
                    app.XSlider.Value = app.current_params.FY;
                    app.YSlider.Value = app.current_params.FZ;
            end
            plot_kwave(app);
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            plot_kwave(app);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            plot_kwave(app);
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)

            changingValue = event.Value;
            plot_kwave(app, changingValue);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'xaxis')
            ylabel(app.UIAxes, 'yaxis')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'xaxis')
            ylabel(app.UIAxes_2, 'yaxis')
            app.DisplayIntensityProfileCheckBox = uicheckbox(app.UIFigure);
            app.DisplayIntensityProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayIntensityProfileCheckBoxValueChanged, true);
            app.DisplayIntensityProfileCheckBox.Text = 'Display Intensity Profile';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.ResettoFocusButton = uibutton(app.UIFigure, 'push');
            app.ResettoFocusButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoFocusButtonPushed, true);
            app.ResettoFocusButton.Text = 'Reset to Focus';
            app.FlipIntensityProfileSwitch = uiswitch(app.UIFigure, 'slider');
            app.FlipIntensityProfileSwitch.Items = {'', 'Flip Intensity Profile'};
            app.FlipIntensityProfileSwitch.ValueChangedFcn = createCallbackFcn(app, @FlipIntensityProfileSwitchValueChanged, true);
            app.FlipIntensityProfileSwitch.Value = '';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'dB', 'Pressure'};
            app.UnitsDropDown.Value = 'dB';
            app.PlaneButtonGroup = uibuttongroup(app.UIFigure);
            app.PlaneButtonGroup.Title = 'Plane';
            app.xyButton = uiradiobutton(app.PlaneButtonGroup);
            app.xyButton.Text = 'xy';
            app.xyButton.Value = true;
            app.xzButton = uiradiobutton(app.PlaneButtonGroup);
            app.xzButton.Text = 'xz';
            app.yzButton = uiradiobutton(app.PlaneButtonGroup);
            app.yzButton.Text = 'yz';
            app.FocusLabel = uilabel(app.UIFigure);
            app.FocusLabel.Text = 'Focus';
            app.FXSliderLabel = uilabel(app.UIFigure);
            app.FXSliderLabel.Text = 'FX';
            app.FXSlider = uislider(app.UIFigure);
            app.FYSliderLabel = uilabel(app.UIFigure);
            app.FYSliderLabel.Text = 'FY';
            app.FYSlider = uislider(app.UIFigure);
            app.FZSliderLabel = uilabel(app.UIFigure);
            app.FZSliderLabel.Text = 'FZ';
            app.FZSlider = uislider(app.UIFigure);
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataviz(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end